classdef ChangeDetectionPair < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ChangedetectionwithNFAclusteringUIFigure  matlab.ui.Figure
        VisualiseButton           matlab.ui.control.Button
        StatusTextArea            matlab.ui.control.TextArea
        StatusTextAreaLabel       matlab.ui.control.Label
        ComputeButton             matlab.ui.control.Button
        InputImagesPanel          matlab.ui.container.Panel
        TestLamp                  matlab.ui.control.Lamp
        LoadTestImageButton       matlab.ui.control.Button
        TestImage                 matlab.ui.control.Image
        RefLamp                   matlab.ui.control.Lamp
        LoadReferenceImageButton  matlab.ui.control.Button
        RefImage                  matlab.ui.control.Image
    end

    
    properties (Access = private)
        img_ref
        img_test
        img_dif
        maxClusters
        pointVec
        ImageViewerApp
        VisualiserApp
        loaded_images = [0,0]
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadReferenceImageButton
        function LoadReferenceImageButtonPushed(app, event)
            [refImagePath,user_canceled] = imgetfile('InitialPath','./');
            if(user_canceled)
                app.StatusTextArea.Value="Image input cancelled";
                return;
            end
            app.img_ref = imread(refImagePath);
            app.RefImage.ImageSource = refImagePath;
            app.StatusTextArea.Value= "Reference image loaded";
            app.VisualiseButton.Enable = false;
            app.RefLamp.Enable = true;
            app.loaded_images(1) = 1; 
            if all(app.loaded_images)
                app.ComputeButton.Enable = true;
            end
        end

        % Button pushed function: LoadTestImageButton
        function LoadTestImageButtonPushed(app, event)
            [testImagePath,user_canceled] = imgetfile('InitialPath','./');
            if(user_canceled)
                app.StatusTextArea.Value="Image input cancelled";
                return;
            end
            app.img_test = imread(testImagePath);
            app.TestImage.ImageSource = testImagePath;
            app.StatusTextArea.Value="Test image loaded";
            app.VisualiseButton.Enable = false;
            app.TestLamp.Enable = true;
            app.loaded_images(2) = 1;
            if all(app.loaded_images)
                app.ComputeButton.Enable = true;
            end
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            app.StatusTextArea.Value="Computing difference map";
            app.img_dif = compare(app.img_ref,app.img_test);
            %app.DifImage.ImageSource = ind2rgb((uint8(rescale(app.img_dif,0,255))),gray(256));
            app.StatusTextArea.Value="Performing NFA clustering";
            th = prctile(app.img_dif(:),97);
            ccf = 0.01;
            app.img_dif = imresize(app.img_dif,[90 NaN]);
            [app.maxClusters,app.pointVec,debug] = NFAC_gl(app.img_dif,ccf,th);
            app.StatusTextArea.Value="NFA clustering done";
            
            app.VisualiseButton.Enable = true;
            app.VisualiserApp = Visualiser(app,app.maxClusters,app.pointVec,app.img_dif,app.img_test);
        end

        % Image clicked function: RefImage, TestImage
        function ImageClicked(app, event)
            src = event.Source.ImageSource;
            if isstring(src)  
                if src == ""
                    return;
                end
            end
            app.ImageViewerApp = ImageViewer(app,src);
            
        end

        % Button pushed function: VisualiseButton
        function VisualiseButtonPushed(app, event)
            app.VisualiserApp = Visualiser(app,app.maxClusters,app.pointVec,app.img_dif,app.img_test);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ChangedetectionwithNFAclusteringUIFigure = uifigure('Visible', 'off');
            app.ChangedetectionwithNFAclusteringUIFigure.Name = 'Change detection with NFA clustering';
            app.InputImagesPanel = uipanel(app.ChangedetectionwithNFAclusteringUIFigure);
            app.InputImagesPanel.Title = 'Input Images';
            app.RefImage = uiimage(app.InputImagesPanel);
            app.RefImage.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.LoadReferenceImageButton = uibutton(app.InputImagesPanel, 'push');
            app.LoadReferenceImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadReferenceImageButtonPushed, true);
            app.LoadReferenceImageButton.Text = 'Load Reference Image';
            app.RefLamp = uilamp(app.InputImagesPanel);
            app.RefLamp.Enable = 'off';
            app.TestImage = uiimage(app.InputImagesPanel);
            app.TestImage.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.LoadTestImageButton = uibutton(app.InputImagesPanel, 'push');
            app.LoadTestImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTestImageButtonPushed, true);
            app.LoadTestImageButton.Text = 'Load Test Image';
            app.TestLamp = uilamp(app.InputImagesPanel);
            app.TestLamp.Enable = 'off';
            app.ComputeButton = uibutton(app.ChangedetectionwithNFAclusteringUIFigure, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Enable = 'off';
            app.ComputeButton.Text = 'Compute';
            app.StatusTextAreaLabel = uilabel(app.ChangedetectionwithNFAclusteringUIFigure);
            app.StatusTextAreaLabel.Text = 'Status';
            app.StatusTextArea = uitextarea(app.ChangedetectionwithNFAclusteringUIFigure);
            app.StatusTextArea.Editable = 'off';
            app.VisualiseButton = uibutton(app.ChangedetectionwithNFAclusteringUIFigure, 'push');
            app.VisualiseButton.ButtonPushedFcn = createCallbackFcn(app, @VisualiseButtonPushed, true);
            app.VisualiseButton.Enable = 'off';
            app.VisualiseButton.Text = 'Visualise';
            % Show the figure after all components are created
            app.ChangedetectionwithNFAclusteringUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ChangeDetectionPair

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ChangedetectionwithNFAclusteringUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ChangedetectionwithNFAclusteringUIFigure)
        end
    end
end