classdef ImageViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        DismissButton  matlab.ui.control.Button
        UIAxes         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, imgSrc)
            app.CallingApp = caller;
            
            if size(imgSrc,1)==1 %it's a string
                img = imread(imgSrc);
            else
                img = imgSrc;    
            end
         
            app.UIFigure.Position(3:4) = [size(img,2)+70,size(img,1)+70];
            app.UIAxes.Position(3:4) = [size(img,2),size(img,1)]; 
            imagesc(app.UIAxes, img);
            axis(app.UIAxes,'off');
        end

        % Button pushed function: DismissButton
        function DismissButtonPushed(app, event)
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.DismissButton = uibutton(app.UIFigure, 'push');
            app.DismissButton.ButtonPushedFcn = createCallbackFcn(app, @DismissButtonPushed, true);
            app.DismissButton.Text = 'Dismiss';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageViewer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end